classdef CNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        SearchimageButton          matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        RunCNNButton               matlab.ui.control.Button
        IterationsSpinnerLabel     matlab.ui.control.Label
        IterationsSpinner          matlab.ui.control.Spinner
        TemplateDropDownLabel      matlab.ui.control.Label
        TemplateDropDown           matlab.ui.control.DropDown
        A00                        matlab.ui.control.NumericEditField
        A01                        matlab.ui.control.NumericEditField
        A02                        matlab.ui.control.NumericEditField
        A10                        matlab.ui.control.NumericEditField
        A11                        matlab.ui.control.NumericEditField
        A12                        matlab.ui.control.NumericEditField
        A20                        matlab.ui.control.NumericEditField
        A21                        matlab.ui.control.NumericEditField
        A22                        matlab.ui.control.NumericEditField
        B00                        matlab.ui.control.NumericEditField
        B01                        matlab.ui.control.NumericEditField
        B02                        matlab.ui.control.NumericEditField
        B10                        matlab.ui.control.NumericEditField
        B11                        matlab.ui.control.NumericEditField
        B12                        matlab.ui.control.NumericEditField
        B20                        matlab.ui.control.NumericEditField
        B21                        matlab.ui.control.NumericEditField
        B22                        matlab.ui.control.NumericEditField
        z                          matlab.ui.control.NumericEditField
        ALabel                     matlab.ui.control.Label
        BLabel                     matlab.ui.control.Label
        zLabel                     matlab.ui.control.Label
        SearchinitialstateButton   matlab.ui.control.Button
        UIAxes_4                   matlab.ui.control.UIAxes
        InitialstateDropDownLabel  matlab.ui.control.Label
        InitialstateDropDown       matlab.ui.control.DropDown
    end

    
    methods (Access = private)

        function [x,U] = appendBoundaries(~,x,U,r)
            
            for i=1:r
                [M,N] = size(U);
                x=[-ones(1,N);x;-ones(1,N)];
                U=[-ones(1,N);U;-ones(1,N)];
                NewCol = -ones(M+2,1);
                NewColOnes = -ones(M+2,1);
                x=[NewCol x NewCol];
                U=[NewColOnes U NewColOnes];
            end
        end
        function [x,U] = appendBoundaries2(~,x,U,r)
            
            for i=1:r
                [M,N] = size(U);
                x=[zeros(1,N);x;zeros(1,N)];
                U=[zeros(1,N);U;zeros(1,N)];
                NewCol = zeros(M+2,1);
                NewColOnes = zeros(M+2,1);
                x=[NewCol x NewCol];
                U=[NewColOnes U NewColOnes];
            end
            % Now actual size is M+2*r N+2*r
        end    
    
        % Edit gene fields from TemplateDropDown
        function setParameters(app)
            switch app.TemplateDropDown.Value
                case "Figure reconstruction A"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=8;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=8;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=6;
                    
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Figure reconstruction B"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=8;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=2;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                case "Concave-filling A"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=8;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=1;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Concave-filling B"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=8;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=8;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Hole-filling A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=5;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-1;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Hole-filling B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=6;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Filled contour extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=2;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-4;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                 case "Filled contour extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=2;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-4.5;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Selected objects extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=7;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-1;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Selected objects extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=7.5;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-1.5;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Face-vase-illusion A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=-6;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Face-vase-illusion B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=-7;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-1;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Solid black framed area extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-2;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Solid black framed area extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=-0.4;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-2;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Global connectivity detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=-1;app.B02.Value=0;
                    app.B10.Value=-1;app.B11.Value=6;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=-1;app.B22.Value=0;
                    % z
                    app.z.Value=-8;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Global connectivity detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=-1;app.B02.Value=0;
                    app.B10.Value=-1;app.B11.Value=5;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=-1;app.B22.Value=0;
                    % z
                    app.z.Value=-7;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Point removal A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=1;app.B01.Value=1;app.B02.Value=1;
                    app.B10.Value=1;app.B11.Value=8;app.B12.Value=1;
                    app.B20.Value=1;app.B21.Value=1;app.B22.Value=1;
                    % z
                    app.z.Value=-2;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point removal B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=1;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=4;app.B12.Value=1;
                    app.B20.Value=0;app.B21.Value=1;app.B22.Value=0;
                    % z
                    app.z.Value=-2;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=1;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.z.Value=-9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=-1;app.B02.Value=0;
                    app.B10.Value=-1;app.B11.Value=1;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=-1;app.B22.Value=0;
                    % z
                    app.z.Value=-5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Edge detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.z.Value=-2;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Edge detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.z.Value=-0.5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Convex corner detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=4;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.z.Value=-4;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Convex corner detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.z.Value=-8.5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Erosion A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=1;app.B01.Value=1;app.B02.Value=1;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=1;app.B21.Value=1;app.B22.Value=1;
                    % z
                    app.z.Value=-9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Erosion B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=1;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=0;app.B21.Value=1;app.B22.Value=0;
                    % z
                    app.z.Value=-5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Dilation A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=1;app.B01.Value=1;app.B02.Value=1;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=1;app.B21.Value=1;app.B22.Value=1;
                    % z
                    app.z.Value=9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Dilation B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=1;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=0;app.B21.Value=1;app.B22.Value=0;
                    % z
                    app.z.Value=5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Right edge detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-3;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                   
                case "Right edge detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=-2;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=-4;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Shadow projection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=2;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=2;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Shadow projection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=1;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Horizontal hole detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=2;app.A12.Value=-1;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Horizontal hole detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=2;app.A11.Value=2;app.A12.Value=-2;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Inverse half-toning A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=0;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0.08;app.B01.Value=0.08;app.B02.Value=0.08;
                    app.B10.Value=0.08;app.B11.Value=0.36;app.B12.Value=0.08;
                    app.B20.Value=0.08;app.B21.Value=0.08;app.B22.Value=0.08;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Inverse half-toning B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=0;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0.18;app.B02.Value=0;
                    app.B10.Value=0.18;app.B11.Value=0.38;app.B12.Value=0.18;
                    app.B20.Value=0;app.B21.Value=0.18;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Half-toning A"
                    % A
                    app.A00.Value=-0.07;app.A01.Value=-0.1;app.A02.Value=-0.07;
                    app.A10.Value=-0.1;app.A11.Value=3.68;app.A12.Value=-0.1;
                    app.A20.Value=-0.07;app.A21.Value=-0.1;app.A22.Value=-0.07;
                    % B
                    app.B00.Value=0.07;app.B01.Value=0.1;app.B02.Value=0.07;
                    app.B10.Value=0.1;app.B11.Value=0.32;app.B12.Value=0.1;
                    app.B20.Value=0.07;app.B21.Value=0.1;app.B22.Value=0.07;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                
                case "Half-toning B"
                    % A
                    app.A00.Value=0;app.A01.Value=-0.18;app.A02.Value=0;
                    app.A10.Value=-0.18;app.A11.Value=3.72;app.A12.Value=-0.18;
                    app.A20.Value=0;app.A21.Value=-0.18;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0.18;app.B02.Value=0;
                    app.B10.Value=0.18;app.B11.Value=0.28;app.B12.Value=0.18;
                    app.B20.Value=0;app.B21.Value=0.18;app.B22.Value=0;
                    % z
                    app.z.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Edge detection"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8; app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.z.Value=-0.5;
                    
                    app.InitialstateDropDown.Value="All 0";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
            end
            
        end
        
        % Get genes from edit fields
        function [A,B,z]=getGenes(app)
            A=[ app.A00.Value app.A01.Value app.A02.Value;
                app.A10.Value app.A11.Value app.A12.Value;
                app.A20.Value app.A21.Value app.A22.Value];
            
            B=[ app.B00.Value app.B01.Value app.B02.Value;
                app.B10.Value app.B11.Value app.B12.Value;
                app.B20.Value app.B21.Value app.B22.Value];
            
            z=app.z.Value;
        end


    end
    

    methods (Access = private)

        % Button pushed function: SearchimageButton
        function SearchimageButtonPushed(app, event)
            global image
            global gray
            try
                [filename, pathname]=uigetfile({'*.jpg; *png'},'File Selector');
                fullpathname = strcat(pathname,filename);
                image=imread(fullpathname);
                gray=rgb2gray(image);
                imshow(gray,'Parent', app.UIAxes_2);
            catch
                %warning('No image selected');
                
            end
        end

        % Button pushed function: RunCNNButton
        function RunCNNButtonPushed(app, event)

            % Settings
            %set(app.RunCNNButton, 'Enable', 'off');
            set(app.RunCNNButton, 'Visible', 'off');
            %set(app.StopButton, 'Enable', 'on');
            %set(app.StopButton, 'Visible', 'on');
            %set(app.RunningLabel, 'Visible', 'on');
            %app.RunningLabel.Visible = 'on';
            %app.RunningLabel.Text = 'Running.......';
            
            
            %% Prepare input
            global gray;
            global initial;
            global image;
            
            Idouble=im2double(gray);    % uint8[0-255] to double[0-1]: white: 0 black: 1
            Idouble=-(Idouble*2-1);     % [0-1] to [-1,1]: black: +1    white: -1
            U=Idouble;
            

            [M,N] = size(U);            % Original input dimensions
            
            %% Prepare initial state
            switch app.InitialstateDropDown.Value
                case "All 0"
                    x=zeros(M,N);
                    xrev=(1-x)/2;
                    imshow(xrev,'Parent', app.UIAxes_4);
                case "All 1"
                    x=ones(M,N);
                    xrev=(1-x)/2;
                    imshow(xrev,'Parent', app.UIAxes_4);
                case "Same image"
                    x=U;
                    xrev=(1-x)/2;
                    imshow(xrev,'Parent', app.UIAxes_4);
                case "Custom image"
                    % initial image to double
                    Idouble=im2double(initial);
                    % Convert to interval [-1,1] white: -1   black: +1
                    Idouble=-(Idouble*2-1);
                    x=Idouble;
            end
            
            % Get parameters
            [A,B,z]=getGenes(app);
            
            % Radius
            r=1;
            
            % Add extra boundaries
            [x,U]=appendBoundaries2(app,x,U,r);
            % Constrictions to -1
            x(1+r,:)=-1;    x(M+r,:)=-1;    % Boundary rows     (1+r):  1 -> extra boundary
            x(:,1+r)=-1;    x(:,N+r)=-1;    % Boundary columns          r -> actual boundary
            
            % CNN
            global iterations;
            %y=0.5*abs(x+1)-0.5*abs(x-1);
            dt=0.1;
            for it=1:iterations
                %set(app.RunCNNButton, 'Enable', 'off');
                y=0.5*abs(x+1)-0.5*abs(x-1);
                for j = 1+r+1:N+r-1     % 1+r: actual boundary. Should be calculated? Always -1
                    for i = 1+r+1:M+r-1
                        x(i,j)=x(i,j) + dt*(-x(i,j)+sum(A.*y(i-r:i+r,j-r:j+r),"all")+sum(B.*U(i-r:i+r,j-r:j+r),"all")+z);
                        %y(i,j)=0.5*abs(x(i,j)+1)-0.5*abs(x(i,j)-1);
                        if(x(i,j)<-1)
                            x(i,j)=-1;
                        elseif(x(i,j)>1)
                            x(i,j)=1;
                        end
                    end
                end
                %Constrictions to -1 NO BORRAR
               %x(1+r,:)=-1;    x(M+r,:)=-1;    % Boundary rows
               %x(:,1+r)=-1;    x(:,N+r)=-1;    % Boundary columns
            end
           
            
            % Revert [-1,+1] to [0-1]: white: 0  black: 1
            result=(1-x)/2;
            % Delete extra boundaries
            [M,N]=size(result);
            result(1+M-r:M,:)=[]; result(1:r,:)=[]; % First and last rows
            result(:,1+N-r:N)=[]; result(:,1:r)=[]; % First and last columns
            
            % Show result
            imshow(result,'Parent', app.UIAxes_3);
            
            %Color
            %Cdouble=im2double(image);
            %RGB=im2double(image);
            Ired=im2double(image(:,:,1));
            Igreen=im2double(image(:,:,2));
            Iblue=im2double(image(:,:,3));

            
            % I(signed) converted to black -> +1 white -> -1
            %Cdouble=-(Cdouble*2-1);
            %C=Cdouble;
            
            % Sharpen
            x=(x+1)/2;
            x(1+M-r:M,:)=[]; x(1:r,:)=[]; % First and last rows
            x(:,1+N-r:N)=[]; x(:,1:r)=[]; % First and last columns
            %sharpen=U;
            for j=1:N-2
                for i=1:M-2
                    if(x(i,j)>0.5)
                        Ired(i,j,1)=Ired(i,j,1)*(x(i,j)+0.2);
                         Igreen(i,j,1)=Igreen(i,j,1)*(x(i,j)+0.2);
                          Iblue(i,j,1)=Iblue(i,j,1)*(x(i,j)+0.2);
                    end
                end
            end
            rgb(:,:,1)=Ired;
            rgb(:,:,2)=Igreen;
            rgb(:,:,3)=Iblue;
            
            imwrite(rgb(:,:,:),"sharpenColor.png")
            
            % Final settings
            % set(app.RunCNNButton, 'Enable', 'on');
            %set(app.StopButton, 'Visible', 'off');
            set(app.RunCNNButton, 'Visible', 'on');
            %set(app.RunningLabel, 'Visible', 'off');
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            value = app.IterationsSpinner.Value;
            global iterations;
            iterations=value;
        end

        % Value changed function: TemplateDropDown
        function TemplateDropDownValueChanged(app, event)
            
            setParameters(app);
            
        end

        % Button pushed function: SearchinitialstateButton
        function SearchinitialstateButtonPushed(app, event)
            global initial
            try
                [filename, pathname]=uigetfile({'*.jpg; *png'},'File Selector');
                fullpathname = strcat(pathname,filename);
                initial=imread(fullpathname);
                initial=rgb2gray(initial);
                imshow(initial,'Parent', app.UIAxes_4);
                set(app.RunCNNButton, 'Enable', 'on');
            catch
                %warning('No image selected');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.SearchimageButton = uibutton(app.UIFigure, 'push');
            app.SearchimageButton.ButtonPushedFcn = createCallbackFcn(app, @SearchimageButtonPushed, true);
            app.SearchimageButton.Text = 'Search image';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Input')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Output')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.RunCNNButton = uibutton(app.UIFigure, 'push');
            app.RunCNNButton.ButtonPushedFcn = createCallbackFcn(app, @RunCNNButtonPushed, true);
            app.RunCNNButton.Text = 'Run CNN';
            app.IterationsSpinnerLabel = uilabel(app.UIFigure);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.UIFigure);
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.IterationsSpinner.Value = 3;
            app.TemplateDropDownLabel = uilabel(app.UIFigure);
            app.TemplateDropDownLabel.Text = 'Template';
            app.TemplateDropDown = uidropdown(app.UIFigure);
            app.TemplateDropDown.Items = {'----------------', 'Figure reconstruction A', 'Figure reconstruction B', 'Concave-filling A', 'Concave-filling B', 'Hole-filling A', 'Hole-filling B', 'Filled contour extraction A', 'Filled contour extraction B', 'Selected objects extraction A', 'Selected objects extraction B', 'Face-vase-illusion A', 'Face-vase-illusion B', 'Solid black framed area extraction A', 'Solid black framed area extraction B', 'Global connectivity detection A', 'Global connectivity detection B', 'Point removal A', 'Point removal B', 'Point extraction A', 'Point extraction B', 'Edge detection A', 'Edge detection B', 'Convex corner detection A', 'Convex corner detection B', 'Erosion A', 'Erosion B', 'Dilation A', 'Dilation B', 'Right edge detection A', 'Right edge detection B', 'Shadow projection A', 'Shadow projection B', 'Horizontal hole detection A', 'Horizontal hole detection B', 'Inverse half-toning A', 'Inverse half-toning B', 'Half-toning A', 'Half-toning B', 'Edge detection'};
            app.TemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateDropDownValueChanged, true);
            app.TemplateDropDown.Value = '----------------';
            app.A00 = uieditfield(app.UIFigure, 'numeric');
            app.A01 = uieditfield(app.UIFigure, 'numeric');
            app.A02 = uieditfield(app.UIFigure, 'numeric');
            app.A10 = uieditfield(app.UIFigure, 'numeric');
            app.A11 = uieditfield(app.UIFigure, 'numeric');
            app.A12 = uieditfield(app.UIFigure, 'numeric');
            app.A20 = uieditfield(app.UIFigure, 'numeric');
            app.A21 = uieditfield(app.UIFigure, 'numeric');
            app.A22 = uieditfield(app.UIFigure, 'numeric');
            app.B00 = uieditfield(app.UIFigure, 'numeric');
            app.B01 = uieditfield(app.UIFigure, 'numeric');
            app.B02 = uieditfield(app.UIFigure, 'numeric');
            app.B10 = uieditfield(app.UIFigure, 'numeric');
            app.B11 = uieditfield(app.UIFigure, 'numeric');
            app.B12 = uieditfield(app.UIFigure, 'numeric');
            app.B20 = uieditfield(app.UIFigure, 'numeric');
            app.B21 = uieditfield(app.UIFigure, 'numeric');
            app.B22 = uieditfield(app.UIFigure, 'numeric');
            app.z = uieditfield(app.UIFigure, 'numeric');
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A =';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B =';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z =';
            app.SearchinitialstateButton = uibutton(app.UIFigure, 'push');
            app.SearchinitialstateButton.ButtonPushedFcn = createCallbackFcn(app, @SearchinitialstateButtonPushed, true);
            app.SearchinitialstateButton.Text = 'Search initial state';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Initial state')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.InitialstateDropDownLabel = uilabel(app.UIFigure);
            app.InitialstateDropDownLabel.Enable = 'off';
            app.InitialstateDropDownLabel.Visible = 'off';
            app.InitialstateDropDownLabel.Text = 'Initial state';
            app.InitialstateDropDown = uidropdown(app.UIFigure);
            app.InitialstateDropDown.Items = {'All 0', 'All 1', 'Same image', 'Custom image'};
            app.InitialstateDropDown.Enable = 'off';
            app.InitialstateDropDown.Visible = 'off';
            app.InitialstateDropDown.Value = 'All 0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CNN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end